classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExtractSmoothSurfacesButton  matlab.ui.control.Button
        ScreeningButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ProcessSubjectsButton        matlab.ui.control.Button
        GroupOutputsButton           matlab.ui.control.Button
        EstimationModeButtonGroup    matlab.ui.container.ButtonGroup
        OverwriteButton              matlab.ui.control.RadioButton
        AppendButton                 matlab.ui.control.RadioButton
        OutputFormatButtonGroup      matlab.ui.container.ButtonGroup
        CNNPButton                   matlab.ui.control.RadioButton
        FSButton                     matlab.ui.control.RadioButton
        Tree2                        matlab.ui.container.CheckBoxTree
        LobeNode                     matlab.ui.container.TreeNode
        LUTNode                      matlab.ui.container.TreeNode
        HemisphereNode               matlab.ui.container.TreeNode
        MultiscaleNode               matlab.ui.container.TreeNode
        HigherScalesNode             matlab.ui.container.TreeNode
        LowerScalesNode              matlab.ui.container.TreeNode
        NoScalingNode                matlab.ui.container.TreeNode
        Button                       matlab.ui.control.Button
        HemisphereButtonGroup        matlab.ui.container.ButtonGroup
        SumButton                    matlab.ui.control.RadioButton
        AverageButton                matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        RightButton                  matlab.ui.control.RadioButton
        LeftButton                   matlab.ui.control.RadioButton
        EditField                    matlab.ui.control.EditField
        AddDatasetButton             matlab.ui.control.Button
        Tree                         matlab.ui.container.CheckBoxTree
        CNNPCorticalFoldingLabel     matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end


    % New features to add
    
    % Requirememtns
    % 1) Subject data directly under "freesurface" folder
    % 2) Single "freesurface" folder
    % 3) No "." on the naming of any folder of interes
    % 4) Simulink installed on MatLab
    % 5) dos2unix library installed
    
    % User dependant requirements
    % a) Run Matlab with sudo access and then run the UI

    properties (Access = private)
        Smth_path = cell(0);
        Glba_Root = cell(0); % Description
        dirSlct_Path = cd;
        FS_dir = [];
    end

    methods (Access = private)

        %------------------------------------------------------------------
        % Function Enable/Disable
        function stt_0 = Function_Lock(app, State,stt_nw)
            stt_0 = [];
            if State==0
                stt_0(1) = app.AddDatasetButton.Enable;
                app.AddDatasetButton.Enable = State;

                stt_0(2) = app.Button.Enable;
                app.Button.Enable = State;

                stt_0(3) = app.Tree.Enable;
                app.Tree.Enable = State;

                stt_0(4) = app.HemisphereButtonGroup.Enable;
                app.HemisphereButtonGroup.Enable = State;

                stt_0(5) = app.Tree2.Enable;
                app.Tree2.Enable = State;

                stt_0(6) = app.ScreeningButton.Enable;
                app.ScreeningButton.Enable = State;

                stt_0(7) = app.ExtractSmoothSurfacesButton.Enable;
                app.ExtractSmoothSurfacesButton.Enable = State;

                stt_0(8) = app.EstimationModeButtonGroup.Enable;
                app.EstimationModeButtonGroup.Enable = State;

                stt_0(9) = app.ProcessSubjectsButton.Enable;
                app.ProcessSubjectsButton.Enable = State;

                stt_0(10) = app.OutputFormatButtonGroup.Enable;
                app.OutputFormatButtonGroup.Enable = State;

                stt_0(11) = app.GroupOutputsButton.Enable;
                app.GroupOutputsButton.Enable = State;

            else
                app.AddDatasetButton.Enable = stt_nw(1);
                app.Button.Enable = stt_nw(2);
                app.Tree.Enable = stt_nw(3);

                app.HemisphereButtonGroup.Enable = stt_nw(4);

                app.Tree2.Enable = stt_nw(5);

                app.ScreeningButton.Enable = stt_nw(6);
                app.ExtractSmoothSurfacesButton.Enable = stt_nw(7);

                app.EstimationModeButtonGroup.Enable = stt_nw(8);
                app.ProcessSubjectsButton.Enable = stt_nw(9);

                app.OutputFormatButtonGroup.Enable = stt_nw(10);
                app.GroupOutputsButton.Enable = stt_nw(11);
            end

        end

        %------------------------------------------------------------------
        % Function to generate the configuration file
        function ConfigTable(app, csv_nm, MD)
            Tab = table();
            L = max([6,length(app.Glba_Root)]);

            Tab.Root = repmat({''},L,1);
            Tab.FS   = repmat({''},L,1);
            Tab.Subj = repmat({''},L,1);
            Tab.Ses  = repmat({''},L,1);
            Tab.Mode = nan(L,1);

            % Configuration parameters
            % Hemisphere mode selection
            HMd_typ = {'Left','Right','Both','Average','Sum'};
            HMd_slc = app.HemisphereButtonGroup.SelectedObject.Text;
            hm_dx = find(strcmp(HMd_typ,HMd_slc));

            % Lobe mode, Atlas selection
            [~,b,~] = intersect({app.Tree2.Children(1).Children.Text},...
                {app.Tree2.CheckedNodes.Text});

            lb_dx = sum(b);

            % Multiscaling selection
            [~,b,~] = intersect({app.Tree2.Children(3).Children.Text},...
                {app.Tree2.CheckedNodes.Text});
            scl_dx = '000';
            scl_dx(b) = '1';
            scl_dx = bin2dec(scl_dx);
            %scl_dx = 0;
            
            switch MD
                case 1 % Extraction Mode
                    OwMd = 0;
                    ExMd = app.CNNPButton.Value*2 + app.FSButton.Value;
                case 2 % Estimation Mode
                    OwMd = app.OverwriteButton.Value*2 + app.AppendButton.Value;
                    ExMd = 0;
                case 3 % Screening mode
                    OwMd = 0;
                    ExMd = 0;
            end

            Tab.Mode(1:6) = [lb_dx;... % Estimate parameteres Lobe-Wise with which of the Atlas avaialable
                any(strcmp({app.Tree2.CheckedNodes.Text},'Hemisphere'));... % Estimate parameters Hemisphere-Wise
                scl_dx;...
                OwMd;... % Overwriting Mode
                ExMd;... %  Mode
                hm_dx]; % Hemisphere Mode

            % Introducing the selected dataset root files and the
            % appropriate subject folders
            Avl_DS = {app.Tree.Children.Text};
            Chk_DS = {app.Tree.CheckedNodes.Text};

            for i=1:length(Chk_DS)
                idx = find(strcmp(Avl_DS,Chk_DS{i}));

                % Root Folder
                Tab.Root(i) = app.Glba_Root(idx);

                % Look for FreeSuerfare Folder
                % All folder on Root
                filelist = dir(fullfile(Tab.Root{i}));
                % Folder names 'freesurfer'
                fs_dx = find(strcmp(lower({filelist.name}),'freesurfer'));
                % Directory from Root to FS file
                Tab.FS{i} = fullfile(filelist(fs_dx).folder(length(Tab.Root{i})+2:end),filelist(fs_dx).name);

                % Subjects & Sesions
                listing = dir(fullfile(Tab.Root{i},Tab.FS{i}));
                rdx = contains({listing.name},'.');
                listing(rdx) = [];
                
                sbj = [];
                ssn = [];
                for j=1:length(listing)
                    ss_lis = dir(fullfile(listing(j).folder,listing(j).name,'**/surf*'));
                    rm_dx = ~strcmp({ss_lis.name}','surf');
                    ss_lis(rm_dx) = [];
                    if isempty(ss_lis)% No /surf folder, skip
                        continue;
                    end

                    % Find if there is a sesion or not
                    dx = strfind(ss_lis(1).folder,listing(j).name);
                    aux = char({ss_lis.folder}');
                    Sbj_Ssn = num2cell(aux(:,dx:end),2);
                    dx = strfind(Sbj_Ssn,'/');

                    % Add blanck sesions
                    e_dx = cellfun(@isempty,dx);
                    Sbj_Ssn(e_dx) = cellfun(@horzcat,Sbj_Ssn(e_dx),repmat({'/_*_'},size(Sbj_Ssn(e_dx))),'UniformOutput',false);
                    dx = strfind(Sbj_Ssn,'/');

                    % Concat Subjects
                    asdf = cellfun(@(x,y)(x(1:y-1)),Sbj_Ssn,dx,'UniformOutput',false);
                    aux = [char(asdf'),repmat('||', length(asdf),1)]';
                    bux = aux(:);
                    cux = erase(bux(1:end-2)',' ');
                    sbj = [sbj,replace(cux,'||',' | '),' | '];

                    % Concat Sessions
                    asdf = cellfun(@(x,y)(x(y+1:end)),Sbj_Ssn,dx,'UniformOutput',false);
                    aux = [char(asdf'),repmat('||', length(asdf),1)]';
                    bux = aux(:);
                    cux = erase(bux(1:end-2)',' ');
                    ssn = [ssn,replace(cux,'||',' | '),' | '];

                end

                Tab.Subj(i) = {sbj(1:end-3)};
                Tab.Ses(i)  = {ssn(1:end-3)};

            end

            % Write the csv configuration file
            writetable(Tab,csv_nm);%)
        end

        %------------------------------------------------------------------
        function Phase_1_Btt(app,Md)
                app.HemisphereButtonGroup.Visible = Md;
                app.Tree2.Visible = Md;
                app.EditField.Visible = Md;
        end

        function Phase_2_Btt(app,Md)
            app.UITable.Visible = Md;
            app.ScreeningButton.Visible = Md;
        end
        
        function Phase_3_Btt(app,Md)
                app.ProcessSubjectsButton.Visible = Md;
                app.GroupOutputsButton.Visible = Md;
                app.OutputFormatButtonGroup.Visible = Md;
                app.EstimationModeButtonGroup.Visible = Md;
                app.ExtractSmoothSurfacesButton.Visible = Md;
        end

        %------------------------------------------------------------------
        % Creening function to check the state of the dataset
        function Screening(app)
            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),3);

            % Obtain a report of the subjects with all 
            aux = gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'));

            % Check the subjects with appropriate pial-smooth files
            [bux,app.Smth_path] = Smooth_Test(fullfile(cd,'Local_exe'));

            % Update the table with the obtain data
            rep = table();
            rep.DataSet = aux.dSet;
            rep.Subjects = aux.N;
            rep.Smoothed = bux;
            rep.Processed = aux.cnt;

            % Fill the table with the data
            app.UITable.Data = rep;

            % Allow the use of the Pashe 3 functionalities
            Phase_3_Btt(app,"on")

            if ~isempty(app.Smth_path)
                if ~ispc
                    app.ExtractSmoothSurfacesButton.Enable = "on";
                end
            else
                app.ExtractSmoothSurfacesButton.Enable = "off";
            end
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(cd,'Local_exe')))
        end

        % Button pushed function: AddDatasetButton
        function AddDatasetButtonPushed(app, event)
            % Lock all other fucntions
            stt_0 = Function_Lock(app, 0, []);
            
            % Select the dataset
            New_Path = uigetdir(app.dirSlct_Path,'Select Dataset');

            % No imput provided
            if isnumeric(New_Path)
                if ~New_Path
                    return;
                end
            end

            % Add the file as a parent and the subfolders as parents
            [pTemp,Nd_Name,~] = fileparts(New_Path);

            % Determine if the dataset is duplicated
            if ~isempty(app.Tree.Children)
                % Remove the number of subjects from the name
                for i=1:length(app.Tree.Children)
                    idx = find(app.Tree.Children(i).Text == '[');
                    Ext_DS{i} = app.Tree.Children(i).Text(1:idx-2);
                end

                if any(strcmp(Ext_DS,Nd_Name))
                    idx = find(strcmp(Ext_DS,Nd_Name));
                    for i=1:length(idx)
                        if strcmp(app.Glba_Root{idx(i)},New_Path)
                            msgbox("Dataset already included","","warn")
                            return;
                        end
                    end
                end
            end

            % Check if the dataset includes a FreeSurfer folder
            filelist = dir(New_Path);
            fs_dx = find(strcmp(lower({filelist.name}),'freesurfer'));

            if isempty(fs_dx)
                msgbox("No FreeSurfer data avaialable","Error","error")
                return;
            end

            % Count the number of subject on the dataset
            listing = dir(fullfile(New_Path,filelist(fs_dx).name));
            Ns = sum(~startsWith({listing.name},'.'));

            % Add the dataset option and the number ofn subjects
            uitreenode(app.Tree,"Text",[Nd_Name,' [',num2str(Ns),']']);

            % Add this dataset to the Root List
            app.Glba_Root{end+1} = New_Path;
            app.dirSlct_Path = pTemp;

            %c Re-enable functions
            Function_Lock(app, 1, stt_0);

            % Enable Tree emptying function
            app.Button.Enable = 1;
            

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.Tree.Children)
                app.Tree.Children.delete;
                app.Button.Enable = 0;
                Phase_1_Btt(app,"off");
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
                app.Tree2.CheckedNodes = app.Tree2.Children(3).Children(3);
                app.UITable.Data = []; 
            end
        end

        % Button pushed function: GroupOutputsButton
        function GroupOutputsButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end

            % Control if at least 1 mode has been selected
            if isempty(app.Tree2.CheckedNodes)
                msgbox("No Selected Modes","Error","error");
                return;
            end
            
            % lock all functions
            stt_0 = Function_Lock(app, 0, []);
            
            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),1);

            % Run the csv extraction code
            % prompt = {'Enter output name'};
            % dlgtitle = 'Input';
            % fieldsize = [1 45];
            % definput = {'CFpar'};
            % answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            % out_nm_str = answer{1};
            
            definput = 'CFpar';
            prompt = 'Enter output name';
            Out_frmt = app.OutputFormatButtonGroup.SelectedObject.Text;
            out_nm_str = [];
            if strcmp(Out_frmt,'CNNP')
                [file,out_nm_str{2}] = uiputfile([definput,'.csv'], prompt);
                if isnumeric(file)
                    return;
                end

                out_nm_str{1} = file(1:end-4);
            elseif strcmp(Out_frmt,'FS')
                path = uigetdir(cd,prompt);
                if isnumeric(path)
                    return;
                end
                [out_nm_str{2},out_nm_str{1},~] = fileparts(path);
            end

            gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'),out_nm_str);

            % Renable
            Function_Lock(app, 1, stt_0);

        end

        % Button pushed function: ProcessSubjectsButton
        function ProcessSubjectsButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end

            % Control if at least 1 mode has been selected
            if isempty(app.Tree2.CheckedNodes)
                msgbox("No Selected Modes","Error","error");
                return;
            end
            
            % lock all functions
            stt_0 = Function_Lock(app, 0, []);

            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),2);

            gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'));

            % Procced with the screening
            Screening(app);

            % re-eanble
            Function_Lock(app, 1, stt_0);

        end

        % Button pushed function: ScreeningButton
        function ScreeningButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end
            
            % lock all functions
            stt_0 = Function_Lock(app, 0, []);

            % Procced with the screening
            Screening(app);

            % re-eanble
            Function_Lock(app, 1, stt_0);
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.UITable.Data = [];
            app.Smth_path = [];
            app.ExtractSmoothSurfacesButton.Enable = "off";

            if ~isempty(app.Tree.CheckedNodes)
                Phase_1_Btt(app,"on");

                aux = intersect({app.Tree2.CheckedNodes.Text},...
                    {app.Tree2.Children(1).Children.Text,'Hemisphere'});
                if isempty(aux)
                    Phase_2_Btt(app,"off");
                else
                    Phase_2_Btt(app,"on");
                end
            else
                Phase_1_Btt(app,"off");
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
            end
            
        end

        % Callback function: Tree2
        function Tree2CheckedNodesChanged(app, event)
            app.UITable.Data = [];
            app.Smth_path = [];
            app.ExtractSmoothSurfacesButton.Enable = "off";

            if ~isempty(app.Tree2.CheckedNodes)
                aux = intersect({app.Tree2.CheckedNodes.Text},...
                    {app.Tree2.Children(1).Children.Text,'Hemisphere'});

                bux = intersect({app.Tree2.CheckedNodes.Text},...
                    {app.Tree2.Children(3).Children.Text,});
                if isempty(bux)
                    app.Tree2.CheckedNodes = [app.Tree2.CheckedNodes;app.Tree2.Children(3).Children(3)];
                end
            else
                app.Tree2.CheckedNodes = app.Tree2.Children(3).Children(3);
                aux = [];
            end

            if isempty(aux)
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
            else
                Phase_2_Btt(app,"on");
                if isempty(app.UITable.Data)
                    Phase_3_Btt(app,"off");
                else
                    Phase_3_Btt(app,"on");
                end
            end
        end

        % Button pushed function: ExtractSmoothSurfacesButton
        function ExtractSmoothSurfacesButtonPushed(app, event)
            
            v = ver;
            if ~any(contains({v.Name},'Simulink'))
                msgbox("Install Simulik before runing this function","Error","error")
                return;
            end
            
            % lock all functions
            stt_0 = Function_Lock(app, 0, []);

            % Path to the toolkit for smooth pial estimation
            smth_TK_pth = fullfile(cd,'Local_exe','Toolkit','SmoothPial');

            % Check if lh.pial and rh.pial are available
            Smooth_pth = app.Smth_path;
            rm_dx = true(size(Smooth_pth));
            for i=1:length(Smooth_pth)
                pth_r = fullfile(Smooth_pth{i},'surf','rh.pial');
                pth_l = fullfile(Smooth_pth{i},'surf','lh.pial');

                if isfile(pth_r) && isfile(pth_l)
                    rm_dx(i) = 0;
                end
            end
            Smooth_pth(rm_dx) = [];

            if isempty(Smooth_pth)
                msgbox("No valid data to work with","Done!!","help")
                
                % Reanable
                Function_Lock(app, 1, stt_0);
                
                return;
            end

            % Write the directories for the subjects to analyse
            writematrix(string(Smooth_pth'),fullfile(smth_TK_pth,'subjects.txt'))

            % Write the path to freesurfer package
            if isempty(app.FS_dir)
                if ismac
                    waitfor(msgbox("Select the FreeSurfer VERSION folder","Important","warn"));
                end
                selpath = uigetdir('/Applications','Select the FreeSurfer VERSION folder');
                app.FS_dir = string(selpath);
            end
            writematrix(app.FS_dir,fullfile(smth_TK_pth,'fs_path.txt'));

            % Write the root path of martlab
            writematrix(string([matlabroot,'/bin/matlab']),fullfile(smth_TK_pth,'mtl_path.txt'));

            % Run the Shell for the Smooth Surface
            msgbox("Please Wait, this could take several minutes","Wait","help");
            if isunix && ~ismac % For linux, convert the .sh and run bash
                status = system(['cd ',smth_TK_pth,'; dos2unix gmb_BL_get_smooth_pial.sh']);
                if status
                    msgbox("Install dos2unix package before continuing","Error","error");
                    % Reanable
                    Function_Lock(app, 1, stt_0);
                    return;
                end
                status = system(['cd ',smth_TK_pth,'; bash gmb_BL_get_smooth_pial.sh']);
            elseif isunix && ismac % for max, run directly the sh
                status = system(['cd ',smth_TK_pth,'; sh gmb_BL_get_smooth_pial.sh']);
            end
                        
            %--------------------------------------------------------------
            if ~status
                % Procced with the screening
                Screening(app);
                msgbox("smooth_pial files generated","Done!!","help")

            else
                msgbox("Error during smooth_pial file generation","Error","error")
                app.FS_dir = [];
            end

            % Reanable
            Function_Lock(app, 1, stt_0);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'CompNeurol.png');
            app.CNNPCorticalFoldingLabel = uilabel(app.UIFigure);
            app.CNNPCorticalFoldingLabel.Text = 'CNNP Cortical Folding';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Editable = 'on';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.AddDatasetButton = uibutton(app.UIFigure, 'push');
            app.AddDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatasetButtonPushed, true);
            app.AddDatasetButton.Text = 'Add Dataset';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            app.EditField.Value = 'Modes';
            app.HemisphereButtonGroup = uibuttongroup(app.UIFigure);
            app.HemisphereButtonGroup.Title = 'Hemisphere';
            app.HemisphereButtonGroup.Visible = 'off';
            app.LeftButton = uiradiobutton(app.HemisphereButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.HemisphereButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.HemisphereButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.AverageButton = uiradiobutton(app.HemisphereButtonGroup);
            app.AverageButton.Enable = 'off';
            app.AverageButton.Text = 'Average';
            app.SumButton = uiradiobutton(app.HemisphereButtonGroup);
            app.SumButton.Enable = 'off';
            app.SumButton.Text = 'Sum';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'Trahsbin.png');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Tree2 = uitree(app.UIFigure, 'checkbox');
            app.Tree2.Visible = 'off';
            app.LobeNode = uitreenode(app.Tree2);
            app.LobeNode.Text = 'Lobe';
            app.LUTNode = uitreenode(app.LobeNode);
            app.LUTNode.Text = 'LUT';
            app.HemisphereNode = uitreenode(app.Tree2);
            app.HemisphereNode.Text = 'Hemisphere';
            app.MultiscaleNode = uitreenode(app.Tree2);
            app.MultiscaleNode.Text = 'Multi-scale';
            app.HigherScalesNode = uitreenode(app.MultiscaleNode);
            app.HigherScalesNode.Text = 'Higher Scales';
            app.LowerScalesNode = uitreenode(app.MultiscaleNode);
            app.LowerScalesNode.Text = 'Lower Scales';
            app.NoScalingNode = uitreenode(app.MultiscaleNode);
            app.NoScalingNode.Text = 'No Scaling';
            % Assign Checked Nodes
            app.Tree2.CheckedNodes = [app.NoScalingNode];
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree2CheckedNodesChanged, true);
            app.OutputFormatButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputFormatButtonGroup.Title = 'Output Format';
            app.OutputFormatButtonGroup.Visible = 'off';
            app.FSButton = uiradiobutton(app.OutputFormatButtonGroup);
            app.FSButton.Text = 'FS';
            app.CNNPButton = uiradiobutton(app.OutputFormatButtonGroup);
            app.CNNPButton.Text = 'CNNP';
            app.CNNPButton.Value = true;
            app.EstimationModeButtonGroup = uibuttongroup(app.UIFigure);
            app.EstimationModeButtonGroup.Title = 'Estimation Mode';
            app.EstimationModeButtonGroup.Visible = 'off';
            app.AppendButton = uiradiobutton(app.EstimationModeButtonGroup);
            app.AppendButton.Text = 'Append';
            app.AppendButton.Value = true;
            app.OverwriteButton = uiradiobutton(app.EstimationModeButtonGroup);
            app.OverwriteButton.Text = 'Overwrite';
            app.GroupOutputsButton = uibutton(app.UIFigure, 'push');
            app.GroupOutputsButton.ButtonPushedFcn = createCallbackFcn(app, @GroupOutputsButtonPushed, true);
            app.GroupOutputsButton.Visible = 'off';
            app.GroupOutputsButton.Text = 'Group Outputs';
            app.ProcessSubjectsButton = uibutton(app.UIFigure, 'push');
            app.ProcessSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSubjectsButtonPushed, true);
            app.ProcessSubjectsButton.Visible = 'off';
            app.ProcessSubjectsButton.Text = 'Process Subjects';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'DataSet'; 'Subjects'; 'Smoothed'; 'Processed'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ScreeningButton = uibutton(app.UIFigure, 'push');
            app.ScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @ScreeningButtonPushed, true);
            app.ScreeningButton.Visible = 'off';
            app.ScreeningButton.Text = 'Screening';
            app.ExtractSmoothSurfacesButton = uibutton(app.UIFigure, 'push');
            app.ExtractSmoothSurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSmoothSurfacesButtonPushed, true);
            app.ExtractSmoothSurfacesButton.Enable = 'off';
            app.ExtractSmoothSurfacesButton.Visible = 'off';
            app.ExtractSmoothSurfacesButton.Text = 'Extract Smooth Surfaces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end