classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExtractSmoothSurfacesButton  matlab.ui.control.Button
        ScreeningButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        ProccessSubjectsButton       matlab.ui.control.Button
        GroupOutputsButton           matlab.ui.control.Button
        EstimationModeButtonGroup    matlab.ui.container.ButtonGroup
        OverwriteButton              matlab.ui.control.RadioButton
        AppendButton                 matlab.ui.control.RadioButton
        OutputFormatButtonGroup      matlab.ui.container.ButtonGroup
        CNNPButton                   matlab.ui.control.RadioButton
        FSButton                     matlab.ui.control.RadioButton
        Tree2                        matlab.ui.container.CheckBoxTree
        LobeNode                     matlab.ui.container.TreeNode
        LUTNode                      matlab.ui.container.TreeNode
        HemisphereNode               matlab.ui.container.TreeNode
        Button                       matlab.ui.control.Button
        HemisphereButtonGroup        matlab.ui.container.ButtonGroup
        SumButton                    matlab.ui.control.RadioButton
        AverageButton                matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        RightButton                  matlab.ui.control.RadioButton
        LeftButton                   matlab.ui.control.RadioButton
        EditField                    matlab.ui.control.EditField
        AddDatasetButton             matlab.ui.control.Button
        Tree                         matlab.ui.container.CheckBoxTree
        CNNPCorticalFoldingLabel     matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end


    % New features to add
    
    % Requirememtns
    % 1) Subject data directly under "freesurface" folder
    % 2) Single "freesurface" folder
    % 3) No "." on the naming of any folder of interes
    % 4) Simulink installed on MatLab
    % 5) dos2unix library installed
    
    % User dependant requirements
    % a) Run Matlab with sudo access and then run the UI

    properties (Access = private)
        Smth_path = cell(0);
        Glba_Root = cell(0); % Description
        dirSlct_Path = cd;
        FS_dir = [];
    end

    methods (Access = private)

        %------------------------------------------------------------------
        % Function to generate the configuration file
        function ConfigTable(app, csv_nm, MD)
            Tab = table();
            L = max([5,length(app.Glba_Root)]);

            Tab.Root = repmat({''},L,1);
            Tab.FS   = repmat({''},L,1);
            Tab.Subj = repmat({''},L,1);
            Tab.Ses  = repmat({''},L,1);
            Tab.Mode = nan(L,1);

            % Configuration parameters
            % Hemisphere mode selection
            HMd_typ = {'Left','Right','Both','Average','Sum'};
            HMd_slc = app.HemisphereButtonGroup.SelectedObject.Text;
            hm_dx = find(strcmp(HMd_typ,HMd_slc));

            % Lobe mode, Atlas selection
            app.Tree2.CheckedNodes;
            if any(strcmp({app.Tree2.CheckedNodes.Text},'Lobe'))
                [~,b,~] = intersect({app.Tree2.Children(1).Children.Text},...
                    {app.Tree2.CheckedNodes.Text});
                lb_dx = b(1);
            else
                lb_dx = 0;
            end
            
            switch MD
                case 1 % Extraction Mode
                    OwMd = 0;
                    ExMd = app.CNNPButton.Value*2 + app.FSButton.Value;
                case 2 % Estimation Mode
                    OwMd = app.OverwriteButton.Value*2 + app.AppendButton.Value;
                    ExMd = 0;
                case 3 % Screening mode
                    OwMd = 0;
                    ExMd = 0;
            end

            Tab.Mode(1:5) = [lb_dx;... % Estimate parameteres Lobe-Wise with which of the Atlas avaialable
                any(strcmp({app.Tree2.CheckedNodes.Text},'Hemisphere'));... % Estimate parameters Hemisphere-Wise
                OwMd;... % Overwriting Mode
                ExMd;... %  Mode
                hm_dx]; % Hemisphere Mode

            % Introducing the selected dataset root files and the
            % appropriate subject folders
            Avl_DS = {app.Tree.Children.Text};
            Chk_DS = {app.Tree.CheckedNodes.Text};

            for i=1:length(Chk_DS)
                idx = find(strcmp(Avl_DS,Chk_DS{i}));

                % Root Folder
                Tab.Root(i) = app.Glba_Root(idx);

                % Look for FreeSuerfare Folder
                % All folder on Root
                filelist = dir(fullfile(Tab.Root{i}, '**/*'));
                % Folder names 'freesurfer'
                fs_dx = find(strcmp(lower({filelist.name}),'freesurfer'));
                % Directory from Root to FS file
                Tab.FS{i} = fullfile(filelist(fs_dx).folder(length(Tab.Root{i})+2:end),filelist(fs_dx).name);

                % Subjects [FOR NOW ALL 1ST LEVEL SUBFOLDER ARE SUBJECTS]
                listing = dir(fullfile(Tab.Root{i},Tab.FS{i}));
                rdx = contains({listing.name},'.');
                listing(rdx) = [];
                aux = [char({listing.name}'),repmat(' | ', length(listing),1)]';
                bux = aux(:);
                Tab.Subj(i) = {bux(1:end-3)'};

                % Sesion integration [TO BE IMPLEMENTED]
                aux = repmat('_*_ | ',1,length(listing));
                Tab.Ses(i) = {aux(1:end-3)};
            end

            % Write the csv configuration file
            writetable(Tab,csv_nm);%)
        end

        %------------------------------------------------------------------
        function Phase_1_Btt(app,Md)
                app.HemisphereButtonGroup.Visible = Md;
                app.Tree2.Visible = Md;
                app.EditField.Visible = Md;
        end

        function Phase_2_Btt(app,Md)
            app.UITable.Visible = Md;
            app.ScreeningButton.Visible = Md;
        end
        
        function Phase_3_Btt(app,Md)
                app.ProccessSubjectsButton.Visible = Md;
                app.GroupOutputsButton.Visible = Md;
                app.OutputFormatButtonGroup.Visible = Md;
                app.EstimationModeButtonGroup.Visible = Md;
                app.ExtractSmoothSurfacesButton.Visible = Md;
        end

        %------------------------------------------------------------------
        % Creening function to check the state of the dataset
        function Screening(app)
            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),3);

            % Obtain a report of the subjects with all 
            aux = gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'));

            % Check the subjects with appropriate pial-smooth files
            [bux,app.Smth_path] = Smooth_Test(fullfile(cd,'Local_exe'));

            % Update the table with the obtain data
            rep = table();
            rep.DataSet = aux.dSet;
            rep.Subjects = aux.N;
            rep.Smoothed = bux;
            rep.Proccessed = aux.cnt;

            % Fill the table with the data
            app.UITable.Data = rep;

            % Allow the use of the Pashe 3 functionalities
            Phase_3_Btt(app,"on")

            if ~isempty(app.Smth_path)
                if ~ispc
                    app.ExtractSmoothSurfacesButton.Enable = "on";
                end
            else
                app.ExtractSmoothSurfacesButton.Enable = "off";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(cd,'Local_exe')))
        end

        % Button pushed function: AddDatasetButton
        function AddDatasetButtonPushed(app, event)
            % Select the dataset
            New_Path = uigetdir(app.dirSlct_Path,'Select Dataset');

            % No imput provided
            if isnumeric(New_Path)
                if ~New_Path
                    return;
                end
            end

            % Add the file as a parent and the subfolders as parents
            [pTemp,Nd_Name,~] = fileparts(New_Path);

            % Determine if the dataset is duplicated
            if ~isempty(app.Tree.Children)
                % Remove the number of subjects from the name
                for i=1:length(app.Tree.Children)
                    idx = find(app.Tree.Children(i).Text == '[');
                    Ext_DS{i} = app.Tree.Children(i).Text(1:idx-2);
                end

                if any(strcmp(Ext_DS,Nd_Name))
                    idx = find(strcmp(Ext_DS,Nd_Name));
                    for i=1:length(idx)
                        if strcmp(app.Glba_Root{idx(i)},New_Path)
                            msgbox("Dataset already included","","warn")
                            return;
                        end
                    end
                end
            end

            % Check if the dataset includes a FreeSurfer folder
            filelist = dir(fullfile(New_Path, '**/*'));
            fs_dx = find(strcmp(lower({filelist.name}),'freesurfer'));

            if isempty(fs_dx)
                msgbox("No FreeSurfer data avaialable","Error","error")
                return;
            end

            % Count the number of subject on the dataset
            listing = dir(fullfile(New_Path,...
                fullfile(filelist(fs_dx).folder(length(New_Path)+2:end),filelist(fs_dx).name)));
            Ns = sum(~contains({listing.name},'.'));

            % Add the dataset option and the number ofn subjects
            uitreenode(app.Tree,"Text",[Nd_Name,' [',num2str(Ns),']']);

            % Add this dataset to the Root List
            app.Glba_Root{end+1} = New_Path;
            app.dirSlct_Path = pTemp;

            % Enable Tree emptying function
            app.Button.Enable = 1;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.Tree.Children)
                app.Tree.Children.delete;
                app.Button.Enable = 0;
                Phase_1_Btt(app,"off");
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
                app.Tree2.CheckedNodes = [];
                app.UITable.Data = []; 
            end
        end

        % Button pushed function: GroupOutputsButton
        function GroupOutputsButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end

            % Control if at least 1 mode has been selected
            if isempty(app.Tree2.CheckedNodes)
                msgbox("No Selected Modes","Error","error");
                return;
            end

            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),1);

            % Run the csv extraction code
            prompt = {'Enter output name'};
            dlgtitle = 'Input';
            fieldsize = [1 45];
            definput = {'CFpar'};
            answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
            out_nm_str = answer{1};

            gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'),out_nm_str);

        end

        % Button pushed function: ProccessSubjectsButton
        function ProccessSubjectsButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end

            % Control if at least 1 mode has been selected
            if isempty(app.Tree2.CheckedNodes)
                msgbox("No Selected Modes","Error","error");
                return;
            end

            % All configurations have been checked, lets go with
            % configuration file setup
            ConfigTable(app,fullfile(cd,'Local_exe','Config_Fold.csv'),2);

            gmb_CortFold_Master_V1_1(fullfile(cd,'Local_exe'));

            % Procced with the screening
            Screening(app);

        end

        % Button pushed function: ScreeningButton
        function ScreeningButtonPushed(app, event)
            % Control if datasets have been imported
            if isempty(app.Tree.Children)
                msgbox("No Imported dataset","Error","error");
                return;
            end

            % Control if dataset have been selected
            if isempty(app.Tree.CheckedNodes)
                msgbox("No Selected dataset","Error","error");
                return;
            end
            
            % Procced with the screening
            Screening(app);
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.UITable.Data = [];
            app.Smth_path = [];
            app.ExtractSmoothSurfacesButton.Enable = "off";

            if ~isempty(app.Tree.CheckedNodes)
                Phase_1_Btt(app,"on");
                if isempty(app.Tree2.CheckedNodes)
                    Phase_2_Btt(app,"off");
                else
                    Phase_2_Btt(app,"on");
                end
            else
                Phase_1_Btt(app,"off");
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
            end
            
        end

        % Callback function: Tree2
        function Tree2CheckedNodesChanged(app, event)
            app.UITable.Data = [];
            app.Smth_path = [];
            app.ExtractSmoothSurfacesButton.Enable = "off";

            if isempty(app.Tree2.CheckedNodes)
                Phase_2_Btt(app,"off");
                Phase_3_Btt(app,"off");
            else
                Phase_2_Btt(app,"on");
                if isempty(app.UITable.Data)
                    Phase_3_Btt(app,"off");
                else
                    Phase_3_Btt(app,"on");
                end
            end
        end

        % Button pushed function: ExtractSmoothSurfacesButton
        function ExtractSmoothSurfacesButtonPushed(app, event)
            v = ver;
            if ~any(contains({v.Name},'Simulink'))
                msgbox("Install Simulik before runing this function","Error","error")
            end
            
            % Path to the toolkit for smooth pial estimation
            smth_TK_pth = fullfile(cd,'Local_exe','Toolkit','SmoothPial');
            
            % Write the directories for the subjects to analyse
            writematrix(string(app.Smth_path'),fullfile(smth_TK_pth,'subjects.txt'))

            % Write the path to freesurfer package
            if isempty(app.FS_dir)
                if ismac
                    waitfor(msgbox("Select the FreeSurfer VERSION folder","Important","warn"));
                end
                selpath = uigetdir('/Applications','Select the FreeSurfer VERSION folder');
                app.FS_dir = string(selpath);
            end
            writematrix(app.FS_dir,fullfile(smth_TK_pth,'fs_path.txt'));

            % Write the root path of martlab
            writematrix(string([matlabroot,'/bin/matlab']),fullfile(smth_TK_pth,'mtl_path.txt'));

            % Run the Shell for the Smooth Surface
            msgbox("Please Wait, this could take several minutes","Wait","help");
            if isunix % For linux, convert the .sh and run bash
                status = system(['cd ',smth_TK_pth,'; dos2unix gmb_BL_get_smooth_pial.sh']);
                if status
                    msgbox("Install dos2unix package before continuing","Error","error");
                    return;
                end
                status = system(['cd ',smth_TK_pth,'; bash gmb_BL_get_smooth_pial.sh']);
            elseif ismac % for max, run directly the sh
                status = system(['cd ',smth_TK_pth,'; sh gmb_BL_get_smooth_pial.sh']);
            end
                        
            %--------------------------------------------------------------
            if ~status
                % Procced with the screening
                Screening(app);
                msgbox("smooth_pial files generated","Done!!","help")

            else
                msgbox("Error during smooth_pial file generation","Error","error")
                app.FS_dir = [];
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'CompNeurol.png');
            app.CNNPCorticalFoldingLabel = uilabel(app.UIFigure);
            app.CNNPCorticalFoldingLabel.Text = 'CNNP Cortical Folding';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Editable = 'on';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.AddDatasetButton = uibutton(app.UIFigure, 'push');
            app.AddDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AddDatasetButtonPushed, true);
            app.AddDatasetButton.Text = 'Add Dataset';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Visible = 'off';
            app.EditField.Value = 'Modes';
            app.HemisphereButtonGroup = uibuttongroup(app.UIFigure);
            app.HemisphereButtonGroup.Title = 'Hemisphere';
            app.HemisphereButtonGroup.Visible = 'off';
            app.LeftButton = uiradiobutton(app.HemisphereButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.HemisphereButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.HemisphereButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.AverageButton = uiradiobutton(app.HemisphereButtonGroup);
            app.AverageButton.Enable = 'off';
            app.AverageButton.Text = 'Average';
            app.SumButton = uiradiobutton(app.HemisphereButtonGroup);
            app.SumButton.Enable = 'off';
            app.SumButton.Text = 'Sum';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'Trahsbin.png');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Tree2 = uitree(app.UIFigure, 'checkbox');
            app.Tree2.Visible = 'off';
            app.LobeNode = uitreenode(app.Tree2);
            app.LobeNode.Text = 'Lobe';
            app.LUTNode = uitreenode(app.LobeNode);
            app.LUTNode.Text = 'LUT';
            app.HemisphereNode = uitreenode(app.Tree2);
            app.HemisphereNode.Text = 'Hemisphere';
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree2CheckedNodesChanged, true);
            app.OutputFormatButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputFormatButtonGroup.Title = 'Output Format';
            app.OutputFormatButtonGroup.Visible = 'off';
            app.FSButton = uiradiobutton(app.OutputFormatButtonGroup);
            app.FSButton.Text = 'FS';
            app.CNNPButton = uiradiobutton(app.OutputFormatButtonGroup);
            app.CNNPButton.Text = 'CNNP';
            app.CNNPButton.Value = true;
            app.EstimationModeButtonGroup = uibuttongroup(app.UIFigure);
            app.EstimationModeButtonGroup.Title = 'Estimation Mode';
            app.EstimationModeButtonGroup.Visible = 'off';
            app.AppendButton = uiradiobutton(app.EstimationModeButtonGroup);
            app.AppendButton.Text = 'Append';
            app.AppendButton.Value = true;
            app.OverwriteButton = uiradiobutton(app.EstimationModeButtonGroup);
            app.OverwriteButton.Text = 'Overwrite';
            app.GroupOutputsButton = uibutton(app.UIFigure, 'push');
            app.GroupOutputsButton.ButtonPushedFcn = createCallbackFcn(app, @GroupOutputsButtonPushed, true);
            app.GroupOutputsButton.Visible = 'off';
            app.GroupOutputsButton.Text = 'Group Outputs';
            app.ProccessSubjectsButton = uibutton(app.UIFigure, 'push');
            app.ProccessSubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ProccessSubjectsButtonPushed, true);
            app.ProccessSubjectsButton.Visible = 'off';
            app.ProccessSubjectsButton.Text = 'Proccess Subjects';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'DataSet'; 'Subjects'; 'Smoothed'; 'Proccessed'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ScreeningButton = uibutton(app.UIFigure, 'push');
            app.ScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @ScreeningButtonPushed, true);
            app.ScreeningButton.Visible = 'off';
            app.ScreeningButton.Text = 'Screening';
            app.ExtractSmoothSurfacesButton = uibutton(app.UIFigure, 'push');
            app.ExtractSmoothSurfacesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractSmoothSurfacesButtonPushed, true);
            app.ExtractSmoothSurfacesButton.Enable = 'off';
            app.ExtractSmoothSurfacesButton.Visible = 'off';
            app.ExtractSmoothSurfacesButton.Text = 'Extract Smooth Surfaces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end